classdef GUI_RUNME < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UserData                      matlab.ui.container.Panel
        PleaseSelectUserDataLabel     matlab.ui.control.Label
        ParameterizeButton            matlab.ui.control.Button
        UserHeightcmEditFieldLabel    matlab.ui.control.Label
        UserHeightcmEditField         matlab.ui.control.NumericEditField
        UserMasskgEditFieldLabel      matlab.ui.control.Label
        UserMasskgEditField           matlab.ui.control.NumericEditField
        UserHipDepthcmEditFieldLabel  matlab.ui.control.Label
        UserHipDepthcmEditField       matlab.ui.control.NumericEditField
        TabGroup                      matlab.ui.container.TabGroup
        GroupReactionForcesandHipPowerTab  matlab.ui.container.Tab
        GRF_V                         matlab.ui.control.UIAxes
        GRF_FA                        matlab.ui.control.UIAxes
        Hip_Power                     matlab.ui.control.UIAxes
        ReturntoUserDataButton        matlab.ui.control.Button
        KinematicValidationTab        matlab.ui.container.Tab
        ExIOClearsFloorLampLabel      matlab.ui.control.Label
        ExIOClearsFloorLamp           matlab.ui.control.Lamp
        String_Displacement           matlab.ui.control.UIAxes
        ReturntoUserDataButton_2      matlab.ui.control.Button
        LogFileTab                    matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        ReturntoUserDataButton_3      matlab.ui.control.Button
    end

    
    methods (Access = private)
        %hide other panels
        
        function clearData(app)
            cla(app.GRF_V);
            cla(app.GRF_FA);
            cla(app.Hip_Power);
            cla(app.String_Displacement);
            app.ExIOClearsFloorLamp.Enable = 'off';
            app.TextArea.Value='';
            app.ReturntoUserDataButton.Enable = 'off';
            app.ReturntoUserDataButton_2.Enable = 'off';
            app.ReturntoUserDataButton_3.Enable = 'off';
            app.ExIOClearsFloorLamp.Color = 'white';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.Visible = 'off';
            cla(app.GRF_V);
            cla(app.GRF_FA);
            cla(app.Hip_Power);
            cla(app.String_Displacement);
            app.ExIOClearsFloorLamp.Enable = 'off';
            app.TextArea.Value='';
            app.ExIOClearsFloorLamp.Color = 'white';
            app.ParameterizeButton.Enable = 'on';
            %fixSizing(app);
        end

        % Button pushed function: ParameterizeButton
        function ParameterizeButtonPushed(app, event)
            app.ParameterizeButton.Enable = 'off';
            
            UserH = app.UserHeightcmEditField.Value;
            UserM = app.UserMasskgEditField.Value;
            HIPcm = app.UserHipDepthcmEditField.Value;
            Hip_Depth = HIPcm;
            
            app.UserData.Visible = 'off';
            app.TabGroup.Visible = 'on';
            
            
            %MAIN SCRIPT HERE%
            [forceStruct,sfStuct,fatigueStruct,ground_ReactionX,ground_ReactionY...
                ,hipPower,winterHipPower,floorclearance,GRF_vexo,GRF_fa,log] = ...
                MainTextTester(UserH,UserM,Hip_Depth); %Runs main function
            gaitPercentages = zeros(70,1);
            
            j = 1;
            for i = 0:(100/69):100
                gaitPercentages(j,1)=i;
                j = j + 1;
            end

           
            plot(app.GRF_V,gaitPercentages,GRF_vexo...
                ,gaitPercentages,ground_ReactionY);
            legend(app.GRF_V,"Exoskeleton","Winter's Data");
            
            plot(app.GRF_FA,gaitPercentages,GRF_fa...
                ,gaitPercentages,ground_ReactionX);
            legend(app.GRF_FA,"Exoskeleton","Winter's Data");
            
            plot(app.Hip_Power,gaitPercentages,hipPower... %Replace with calculated hip power
                ,gaitPercentages,winterHipPower);
            legend(app.Hip_Power,"Exoskeleton", "Winter's Data");
            
            plot(app.String_Displacement,gaitPercentages,forceStruct.pawlString1.x_string);
            legend(app.String_Displacement,"String Displacement");
            
            if floorclearance >= 2.3
                app.ExIOClearsFloorLamp.Enable = 'on';
                app.ExIOClearsFloorLamp.Color = 'green';
            else 
                app.ExIOClearsFloorLamp.Enable = 'on';
                app.ExIOClearsFloorLamp.Color = 'red';
            end
            
            app.TextArea.Value = log;
            
            app.ReturntoUserDataButton.Enable = 'on';
            app.ReturntoUserDataButton_2.Enable = 'on';
            app.ReturntoUserDataButton_3.Enable = 'on';
            
        end

        % Button pushed function: ReturntoUserDataButton
        function ReturntoUserDataButtonPushed(app, event)
            app.UserData.Visible = 'on';
            app.TabGroup.Visible = 'off';
            app.ParameterizeButton.Enable = 'on';
            clearData(app);
            %fixSizing(app);
        end

        % Button pushed function: ReturntoUserDataButton_2
        function ReturntoUserDataButton_2Pushed(app, event)
            app.UserData.Visible = 'on';
            app.TabGroup.Visible = 'off';
            app.ParameterizeButton.Enable = 'on';
            clearData(app);
            %fixSizing(app);
        end

        % Button pushed function: ReturntoUserDataButton_3
        function ReturntoUserDataButton_3Pushed(app, event)
            app.UserData.Visible = 'on';
            app.TabGroup.Visible = 'off';
            app.ParameterizeButton.Enable = 'on';
            clearData(app);
            %fixSizing(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UserData = uipanel(app.UIFigure);
            app.UserData.Title = 'User Data ';
            app.PleaseSelectUserDataLabel = uilabel(app.UserData);
            app.PleaseSelectUserDataLabel.Text = 'Please Select User Data:';
            app.ParameterizeButton = uibutton(app.UserData, 'push');
            app.ParameterizeButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterizeButtonPushed, true);
            app.ParameterizeButton.Text = 'Parameterize';
            app.UserHeightcmEditFieldLabel = uilabel(app.UserData);
            app.UserHeightcmEditFieldLabel.Text = 'User Height (cm)';
            app.UserHeightcmEditField = uieditfield(app.UserData, 'numeric');
            app.UserHeightcmEditField.Limits = [153.5 187];
            app.UserHeightcmEditField.Value = 175.62;
            app.UserMasskgEditFieldLabel = uilabel(app.UserData);
            app.UserMasskgEditFieldLabel.Text = 'User Mass (kg)';
            app.UserMasskgEditField = uieditfield(app.UserData, 'numeric');
            app.UserMasskgEditField.Limits = [51.3 110.7];
            app.UserMasskgEditField.Value = 85.52;
            app.UserHipDepthcmEditFieldLabel = uilabel(app.UserData);
            app.UserHipDepthcmEditFieldLabel.Text = 'User Hip Depth (cm)';
            app.UserHipDepthcmEditField = uieditfield(app.UserData, 'numeric');
            app.UserHipDepthcmEditField.Limits = [20.31 27.25];
            app.UserHipDepthcmEditField.Value = 23.78;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GroupReactionForcesandHipPowerTab = uitab(app.TabGroup);
            app.GroupReactionForcesandHipPowerTab.Title = 'Group Reaction Forces and Hip Power';
            app.GRF_V = uiaxes(app.GroupReactionForcesandHipPowerTab);
            title(app.GRF_V, 'Vertical Ground Reaction Force')
            xlabel(app.GRF_V, 'Gait Percentage')
            ylabel(app.GRF_V, 'Force (N)')
            app.GRF_FA = uiaxes(app.GroupReactionForcesandHipPowerTab);
            title(app.GRF_FA, 'Fore - Aft Ground Reaction Forces')
            xlabel(app.GRF_FA, 'Gait Percentage')
            ylabel(app.GRF_FA, 'Force (N)')
            app.Hip_Power = uiaxes(app.GroupReactionForcesandHipPowerTab);
            title(app.Hip_Power, 'Hip Power')
            xlabel(app.Hip_Power, 'Gait Percentage')
            ylabel(app.Hip_Power, 'Power (W)')
            app.ReturntoUserDataButton = uibutton(app.GroupReactionForcesandHipPowerTab, 'push');
            app.ReturntoUserDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoUserDataButtonPushed, true);
            app.ReturntoUserDataButton.Enable = 'off';
            app.ReturntoUserDataButton.Text = 'Return to User Data';
            app.KinematicValidationTab = uitab(app.TabGroup);
            app.KinematicValidationTab.Title = 'Kinematic Validation';
            app.ExIOClearsFloorLampLabel = uilabel(app.KinematicValidationTab);
            app.ExIOClearsFloorLampLabel.Text = 'Ex.IO Clears Floor';
            app.ExIOClearsFloorLamp = uilamp(app.KinematicValidationTab);
            app.String_Displacement = uiaxes(app.KinematicValidationTab);
            title(app.String_Displacement, 'String Displacement (m)')
            xlabel(app.String_Displacement, 'Gait Percentage')
            ylabel(app.String_Displacement, 'String Displament (m)')
            app.ReturntoUserDataButton_2 = uibutton(app.KinematicValidationTab, 'push');
            app.ReturntoUserDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturntoUserDataButton_2Pushed, true);
            app.ReturntoUserDataButton_2.Enable = 'off';
            app.ReturntoUserDataButton_2.Text = 'Return to User Data';
            app.LogFileTab = uitab(app.TabGroup);
            app.LogFileTab.Title = 'Log File';
            app.TextArea = uitextarea(app.LogFileTab);
            app.ReturntoUserDataButton_3 = uibutton(app.LogFileTab, 'push');
            app.ReturntoUserDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReturntoUserDataButton_3Pushed, true);
            app.ReturntoUserDataButton_3.Enable = 'off';
            app.ReturntoUserDataButton_3.Text = 'Return to User Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_RUNME

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end